classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        functionEdit   matlab.ui.control.EditField
        dataEdit       matlab.ui.control.EditField
        Data1Label     matlab.ui.control.Label
        FunctionLabel  matlab.ui.control.Label
        DrawButton     matlab.ui.control.Button
        myAxe          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: DrawButton
        function DrawButtonPushed(app, event)
                try 
                    x = app.dataEdit.Value; 
                    x = str2num(x); 
                    y=5;
                    fx = app.functionEdit.Value; 
                    fx = str2func(['@(x, y)' fx]);
                    z = fx(x,y); 

                    plot(app.myAxe, x,z);
                catch error 
                    switch (error.identifier) 
                        case 'MATLAB:UndefinedFunction'
                            msgbox('Chi nhap ham mot bien','Error','error');
                        otherwise 
                            msgbox({error.identifier,error.message},'Error','error');
                    end
                end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.myAxe = uiaxes(app.UIFigure);
            xlabel(app.myAxe, 'X')
            ylabel(app.myAxe, 'Y')
            zlabel(app.myAxe, 'Z')
            app.DrawButton = uibutton(app.UIFigure, 'push');
            app.DrawButton.ButtonPushedFcn = createCallbackFcn(app, @DrawButtonPushed, true);
            app.DrawButton.Text = 'Draw';
            app.FunctionLabel = uilabel(app.UIFigure);
            app.FunctionLabel.Text = 'Function';
            app.Data1Label = uilabel(app.UIFigure);
            app.Data1Label.Text = 'Data 1';
            app.dataEdit = uieditfield(app.UIFigure, 'text');
            app.functionEdit = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end