classdef Final_Project_Topic4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Endpoint            matlab.ui.control.EditField
        Giihncuiy0Label     matlab.ui.control.Label
        ActionButton        matlab.ui.control.Button
        Error               matlab.ui.control.EditField
        SaisLabel           matlab.ui.control.Label
        NumberOfSteps       matlab.ui.control.EditField
        SonconNLabel        matlab.ui.control.Label
        Startpoint          matlab.ui.control.EditField
        Giihnux0Label       matlab.ui.control.Label
        InitialValue        matlab.ui.control.EditField
        iukinuy0Label       matlab.ui.control.Label
        MethodListbox       matlab.ui.control.ListBox
        ChnphngphptnhLabel  matlab.ui.control.Label
        DiffEquation        matlab.ui.control.EditField
        PTviphnLabel        matlab.ui.control.Label
        Axe                 matlab.ui.control.UIAxes
    end

    methods (Access = private)
        function [x, y] = ole(app,fxy,y0,x0,xn,N)
            h = (xn - x0)/N;
            x = x0:h:xn;
            y = zeros(1,length(x));
            y(1) = y0;
            for i=1:length(x)-1
                y(i+1) =  y(i) + h*fxy(x(i),y(i));
            end
            plot(app.Axe,x,y);
            app.Axe.Title.String = ('Vẽ đồ thị bằng phương pháp Ơ - le');
        end

        function [x, y] = hienantrungdiem(app,fxy,y0,x0,xn,N)
            h = (xn - x0)/N;
            x = x0:h:xn;
            y = zeros(1,length(x));
            y(1) = y0;
            for i = 1:length(x)-1
                y(i+1) = y(i) + h/2 * fxy(x(i),y(i));
                y(i+1) = y(i) + h * fxy(x(i)+h/2,y(i+1));
            end
            plot(app.Axe,x,y);
            app.Axe.Title.String = ('Vẽ đồ thị bằng phương pháp hiện ẩn trung điểm');
        end

        function [x, y] = hienanhinhthang(app,fxy,y0,x0,xn,N,saiso)
            if (isempty(app.Error.Value) == 1)
                msgbox('Hay nhap vao sai so','Error','error');
            else
                h = (xn - x0)/N;
                x = x0:h:xn;
                y = zeros(1,length(x));
                y(1) = y0;
                for i=1:length(x)-1
                    temp = y(i) + h*fxy(x(i),y(i));
                    while(1)
                        y(i+1) = y(i) + h/2 *(fxy(x(i),y(i))+fxy(x(i+1),temp));
                        if(abs(y(i+1) - temp) <= saiso)
                            break;
                        end
                        temp = y(i+1);
                    end
                end
                plot(app.Axe,x,y);
                app.Axe.Title.String = ('Vẽ đồ thị bằng phương pháp hiện ẩn hình thang');
            end            
        end

        function [x, y] = RK(app,fxy,y0,x0,xn,N)
            h = (xn - x0)/N;
            x=x0:h:xn;
            y = zeros(1,length(x));
            y(1) = y0;
            for i=1:length(x)-1
                k1 = h * fxy(x(i),y(i));
                k2 = h * fxy(x(i) + 0.5*h, y(i) + 0.5*k1);
                k3 = h * fxy(x(i) + 0.5*h, y(i) + 0.5*k2);
                k4 = h * fxy(x(i) + h, y(i) + k3);
                y(i+1) = y(i) + 1/6*(k1 + 2*k2 + 2*k3 + k4);
            end
            plot(app.Axe,x,y);
            app.Axe.Title.String = ('Vẽ đồ thị bằng phương pháp R - K');
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ActionButton
        function ActionButtonPushed(app, event)
            try
                method = app.MethodListbox.Value;
                fxy = str2func(['@(x, y)' app.DiffEquation.Value]);
                y0  = str2num(app.InitialValue.Value);
                x0  = str2num(app.Startpoint.Value);
                xn  = str2num(app.Endpoint.Value);
                N   = str2num(app.NumberOfSteps.Value);
                saiso = str2num(app.Error.Value);
                switch method
                    case 'Ơ - le'
                        ole(app,fxy,y0,x0,xn,N);
                    case 'Hiện ẩn hình thang'
                        hienanhinhthang(app,fxy,y0,x0,xn,N,saiso);
                    case 'Hiện ẩn trung điểm'
                        hienantrungdiem(app,fxy,y0,x0,xn,N);
                    case 'R - K'
                        RK(app,fxy,y0,x0,xn,N);
                end
                
            catch error
                switch (error.identifier)
                    case 'MATLAB:m_incomple_statement'
                        msgbox('Hay nhap vao phuong trinh vi phan','Error','error');
                    case 'MATLAB:UndefinedFunction'
                        msgbox('Chi nhap ham 2 bien','Error','error');
                    case 'MATLAB:matrix:singleSubscriptNumelMismatch'
                        msgbox('Hay nhap lai gia tri y0','Error','error');
                    case 'MATLAB:samelen'
                        msgbox('Hay nhap lai gia tri gioi han mang x','Error','error');
                    case 'MATLAB:dimagree'
                        msgbox('Hay nhap lai gia tri N','Error','error');
                    otherwise
                        msgbox({error.identifier,error.message},'Error','error');
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Axe = uiaxes(app.UIFigure);
            title(app.Axe, {''; ''})
            xlabel(app.Axe, 'X')
            ylabel(app.Axe, 'Y')
            zlabel(app.Axe, 'Z')
            app.PTviphnLabel = uilabel(app.UIFigure);
            app.PTviphnLabel.Text = 'PT vi phân:';
            app.DiffEquation = uieditfield(app.UIFigure, 'text');
            app.ChnphngphptnhLabel = uilabel(app.UIFigure);
            app.ChnphngphptnhLabel.Text = 'Chọn phương pháp tính';
            app.MethodListbox = uilistbox(app.UIFigure);
            app.MethodListbox.Items = {'Ơ - le', 'Hiện ẩn hình thang', 'Hiện ẩn trung điểm', 'R - K'};
            app.MethodListbox.Value = 'Ơ - le';
            app.iukinuy0Label = uilabel(app.UIFigure);
            app.iukinuy0Label.Text = 'Điều kiện đầu y0:';
            app.InitialValue = uieditfield(app.UIFigure, 'text');
            app.Giihnux0Label = uilabel(app.UIFigure);
            app.Giihnux0Label.Text = 'Giới hạn đầu x0:';
            app.Startpoint = uieditfield(app.UIFigure, 'text');
            app.SonconNLabel = uilabel(app.UIFigure);
            app.SonconNLabel.Text = 'Số đoạn con N:';
            app.NumberOfSteps = uieditfield(app.UIFigure, 'text');
            app.SaisLabel = uilabel(app.UIFigure);
            app.SaisLabel.Text = 'Sai số:';
            app.Error = uieditfield(app.UIFigure, 'text');
            app.ActionButton = uibutton(app.UIFigure, 'push');
            app.ActionButton.ButtonPushedFcn = createCallbackFcn(app, @ActionButtonPushed, true);
            app.Giihncuiy0Label = uilabel(app.UIFigure);
            app.Giihncuiy0Label.Text = 'Giới hạn cuối y0:';
            app.Endpoint = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Final_Project_Topic4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end